classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UploadImageButton              matlab.ui.control.Button
        SegmentImageButton             matlab.ui.control.Button
        DIGITALIMAGESEGMENTATIONLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        img % Description
        png % Description
        edgeimg % Description
    end


    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImage(app, event)
            [file, path] = uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            
            app.img = imread(fullfile(path,file));
            imshow(app.img,'parent' ,app.UIAxes)
        end

        % Button pushed function: SegmentImageButton
        function SegmentImage(app, event)
            app.edgeimg = edge(rgb2gray(app.img),'canny');
            app.png = bwareaopen(imopen(imfill(imclose(edge(rgb2gray(app.img),'canny'),strel('line',3,0)),'holes'),strel(ones(3,3))),1);
            
            imshow(app.edgeimg, 'parent', app.UIAxes2)
            
            imshow(app.png, 'parent', app.UIAxes3)
            

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Enter Image')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Egde detected image')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Image segmented')
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImage, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.SegmentImageButton = uibutton(app.UIFigure, 'push');
            app.SegmentImageButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentImage, true);
            app.SegmentImageButton.Text = 'Segment Image';
            app.DIGITALIMAGESEGMENTATIONLabel = uilabel(app.UIFigure);
            app.DIGITALIMAGESEGMENTATIONLabel.Text = 'DIGITAL IMAGE SEGMENTATION';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end